classdef RampSpeedApp < matlab.apps.AppBase
    %RAMPSPEEDAPP App Designer shell for checkpointed ramp-speed studies.

    properties (Access = public)
        UIFigure matlab.ui.Figure
        RootGridLayout matlab.ui.container.GridLayout
        SetupPanel matlab.ui.container.Panel
        AnalysisPanel matlab.ui.container.Panel
        SetupGridLayout matlab.ui.container.GridLayout
        SetupTable matlab.ui.control.Table
        CarRoleDropDown matlab.ui.control.DropDown
        RampTypeDropDown matlab.ui.control.DropDown
        LateralModeDropDown matlab.ui.control.DropDown
        SpeedStartEditField matlab.ui.control.NumericEditField
        SpeedStopEditField matlab.ui.control.NumericEditField
        SpeedStepEditField matlab.ui.control.NumericEditField
        SpeedVectorEditField matlab.ui.control.EditField
        NRampEditField matlab.ui.control.NumericEditField
        NBisectEditField matlab.ui.control.NumericEditField
        ResidualToleranceEditField matlab.ui.control.NumericEditField
        ExecutionModeDropDown matlab.ui.control.DropDown
        WorkersEditField matlab.ui.control.NumericEditField
        UnitDropDown matlab.ui.control.DropDown
        BaselineDropDown matlab.ui.control.DropDown
        RunButton matlab.ui.control.Button
        CancelButton matlab.ui.control.Button
        LoadButton matlab.ui.control.Button
        SaveButton matlab.ui.control.Button
        ExportButton matlab.ui.control.Button
        ClearButton matlab.ui.control.Button
        ProgressTextArea matlab.ui.control.TextArea
        MainTabGroup matlab.ui.container.TabGroup
        CapabilityTab matlab.ui.container.Tab
        BalanceTab matlab.ui.container.Tab
        AeroLoadsTab matlab.ui.container.Tab
        SuspensionTab matlab.ui.container.Tab
        RawRampTab matlab.ui.container.Tab
        InspectorDataTab matlab.ui.container.Tab
        CapabilityAxes matlab.ui.control.UIAxes
        BalanceAxes matlab.ui.control.UIAxes
        AeroLoadsAxes matlab.ui.control.UIAxes
        SuspensionAxes matlab.ui.control.UIAxes
        RawRampAxes matlab.ui.control.UIAxes
        InspectorTable matlab.ui.control.Table
        Cars = {}
        Cases = struct.empty(0,1)
        DesignTable = table()
        RunCaseFcnForTest = []
        SetupSpecs = struct.empty(0,1)
        BaselineConfig = struct()
        SetupReadOnly = false
        SetupLabelEditField matlab.ui.control.EditField
        RearArbDropDown matlab.ui.control.DropDown
        FrontSpringDropDown matlab.ui.control.DropDown
        RearSpringDropDown matlab.ui.control.DropDown
        FrontRideHeightEditField matlab.ui.control.NumericEditField
        RearRideHeightEditField matlab.ui.control.NumericEditField
        DriverWeightEditField matlab.ui.control.NumericEditField
        RearWeightDistributionEditField matlab.ui.control.NumericEditField
        AeroMapDropDown matlab.ui.control.DropDown
        DuplicateSetupButton matlab.ui.control.Button
        DeleteSetupButton matlab.ui.control.Button
        SolverProfileDropDown matlab.ui.control.DropDown
        SpeedGridModeDropDown matlab.ui.control.DropDown
        Session = []
    end

    properties (Access = private)
        Future = []
        CompletionFuture = []
        ProgressQueue = []
        SessionTimer = []
        CurrentStudy = []
        SelectedCaseIds = strings(0,1)
        BaselineCaseId = ""
        DisplayUnits = struct("speed","m/s","acceleration","m/s^2", ...
            "force","N","length","m","angle","rad", ...
            "angularRate","rad/s")
        CheckpointPath = ""
        AppVersion = "ramp-speed-app-1"
        LastEvents = struct.empty(0,1)
        LastPlotData = []
        LastComparison = []
        LastInspectorTable = table()
        CancelRequested = false
        SetupEditorUpdating = false
        CapabilityHandles = struct()
        BalanceHandles = struct()
        AeroLoadsHandles = struct()
        SuspensionHandles = struct()
        RawRampHandles = struct()
        ComparisonHandles = struct()
    end

    methods (Access = public)
        function app = RampSpeedApp(varargin)
            createComponents(app);
            registerApp(app,app.UIFigure);

            p = inputParser;
            addParameter(p,"Visible","on");
            addParameter(p,"Cars",[]);
            addParameter(p,"DesignTable",table());
            addParameter(p,"SetupSpecs",[]);
            addParameter(p,"CheckpointPath","");
            addParameter(p,"AppVersion","ramp-speed-app-1");
            parse(p,varargin{:});

            app.AppVersion = string(p.Results.AppVersion);
            app.CheckpointPath = string(p.Results.CheckpointPath);
            if strlength(app.CheckpointPath) == 0
                app.CheckpointPath = fullfile(tempdir, ...
                    "RampSpeedApp-checkpoint.mat");
            end
                         if isempty(p.Results.Cars)
                 [~,app.BaselineConfig] = carConfigBaseline();
                 if isempty(p.Results.SetupSpecs)
                     app.SetupSpecs = app.BaselineConfig.defaultSetup;
                 else
                     app.SetupSpecs = p.Results.SetupSpecs;
                 end
                 [app.Cars,app.Cases,app.DesignTable] = ...
                     rampSpeed.buildSetupCatalog(app.BaselineConfig,app.SetupSpecs);
             else
                 app.Cars = p.Results.Cars;
                 app.SetupSpecs = struct.empty(0,1);
                 app.BaselineConfig = struct();
                 if isempty(p.Results.DesignTable)
                     app.DesignTable = defaultDesignTable();
                 else
                     app.DesignTable = p.Results.DesignTable;
                 end
             end

             sessionOptions = struct("rampType",string(app.RampTypeDropDown.Value), ...
                 "appVersion",app.AppVersion, ...
                 "onProgress",@(event)app.onProgressEvent(event));
             if isempty(app.BaselineConfig) || ...
                     isempty(fieldnames(app.BaselineConfig))
                 app.Session = rampSpeed.RampSpeedSession( ...
                     app.Cars,app.Cases,sessionOptions);
             else
                 sessionOptions.setupSpecifications = app.SetupSpecs;
                 app.Session = rampSpeed.RampSpeedSession( ...
                     app.BaselineConfig,sessionOptions);
             end

             app.UIFigure.Visible = char(string(p.Results.Visible));
            app.initializeSetupEditor();
            app.refreshCaseCatalog();
            app.updateUnitSelection();
            app.ProgressTextArea.Value = "Ready.";
            if nargout == 0
                clear app
            end
        end

        function request = buildRequestForTest(app)
            %BUILDREQUESTFORTEST Expose the production request builder to UI tests.
            request = app.buildRequest();
        end

        function [study,events] = runStudyForTest(app,cars,cases,runCaseFcn)
            %RUNSTUDYFORTEST Run an injected fixture through the session.
            app.SetupSpecs = struct.empty(0,1);
            app.BaselineConfig = struct();
            app.SetupReadOnly = false;
            app.Cars = cars;
            app.Cases = cases(:);
            app.SelectedCaseIds = string({app.Cases.id}).';
            app.RunCaseFcnForTest = runCaseFcn;
            app.ExecutionModeDropDown.Value = "serial";
            app.refreshSetupTable();
            app.Session = rampSpeed.RampSpeedSession( ...
                app.Cars,app.Cases,struct( ...
                "rampType",string(app.RampTypeDropDown.Value), ...
                "appVersion",app.AppVersion, ...
                "onProgress",@(event)app.onProgressEvent(event)));
            app.Session.selectCases(app.SelectedCaseIds);
            request = app.buildRequest();
            request.parallelRequested = false;
            request.numWorkers = 0;
            request.checkpointPath = "";
            job = app.Session.start(request);
            app.Session.poll();
            study = job.study;
            events = job.progressEvents;
            app.syncSessionView();
            app.handleStudyResult(study,events);
        end

        function setModelData(app,cars,designTable)
            %SETMODELDATA Replace the model catalog used by the shell.
            app.Cars = cars;
            app.DesignTable = designTable;
            app.SetupSpecs = struct.empty(0,1);
            app.BaselineConfig = struct();
            app.SetupReadOnly = false;
            app.refreshCaseCatalog();
            app.Session = rampSpeed.RampSpeedSession( ...
                app.Cars,app.Cases,struct( ...
                "rampType",string(app.RampTypeDropDown.Value), ...
                "appVersion",app.AppVersion, ...
                "onProgress",@(event)app.onProgressEvent(event)));
        end

        function setup = duplicateSelectedSetup(app,newId,newLabel)
            %DUPLICATESELECTEDSETUP Duplicate a setup through the session.
            if nargin < 2, newId = []; end
            if nargin < 3, newLabel = []; end
            if app.SetupReadOnly || isempty(app.SetupSpecs)
                error("rampSpeed:setupReadOnly", ...
                    "This study has no editable setup specifications.");
            end
            index = app.selectedSetupIndex();
            source = app.Cases(index).setupSpec;
            if isempty(newId)
                newId = string(source.id) + "-copy";
            end
            app.Session.duplicateSetup(source.id,newId,newLabel);
            app.Session.selectCases(newId);
            app.syncSessionView();
            app.refreshSetupTable();
            app.refreshSetupEditor();
            setup = app.Cases(app.selectedSetupIndex()).setupSpec;
        end

        function setup = editSelectedSetup(app,setup)
            %EDITSELECTEDSETUP Validate and commit through the session.
            if app.SetupReadOnly || isempty(app.SetupSpecs)
                error("rampSpeed:setupReadOnly", ...
                    "This study has no editable setup specifications.");
            end
            index = app.selectedSetupIndex();
            setupId = string(app.Cases(index).id);
            app.Session.editSetup(setupId,setup);
            app.Session.selectCases(setupId);
            app.syncSessionView();
            app.refreshSetupTable();
            app.refreshSetupEditor();
            setup = app.Cases(app.selectedSetupIndex()).setupSpec;
        end

        function deleteSelectedSetup(app)
            %DELETESELECTEDSETUP Delete a setup through the session.
            if app.SetupReadOnly || isempty(app.SetupSpecs)
                error("rampSpeed:setupReadOnly", ...
                    "This study has no editable setup specifications.");
            end
            index = app.selectedSetupIndex();
            setupId = string(app.Cases(index).id);
            app.Session.deleteSetup(setupId);
            app.syncSessionView();
            if ~isempty(app.Cases)
                app.Session.selectCases(app.Cases(1).id);
            end
            app.refreshSetupTable();
            app.refreshSetupEditor();
        end

        function delete(app)
            try
                app.stopSessionTimer();
            catch
            end
            try
                if ~isempty(app.Session)
                    app.Session.cancel();
                end
            catch
            end
            if ~isempty(app.UIFigure) && isvalid(app.UIFigure)
                delete(app.UIFigure);
            end
        end

        function RunButtonPushed(app,~,~)
            if app.SetupReadOnly || ...
                    (~isempty(app.Session) && app.Session.readOnly)
                app.appendProgress("Loaded study is read-only result data; start a new study to run.");
                return
            end
            try
                request = app.buildRequest();
                app.Session.setRampType(request.rampType);
                if isempty(app.SelectedCaseIds)
                    app.SelectedCaseIds = string({app.Cases.id}).';
                end
                app.Session.selectCases(app.SelectedCaseIds);
                app.CancelRequested = false;
                app.setBusyState(true);
                app.ProgressTextArea.Value = "Starting study...";
                job = app.Session.start(request);
                app.syncSessionView();
                if any(string(app.Session.state) == ...
                        ["completed","cancelled","failed"])
                    app.finishSession(job);
                else
                    app.startSessionTimer();
                end
            catch ME
                app.setBusyState(false);
                app.showError(ME);
            end
        end

        function CancelButtonPushed(app,~,~)
            app.CancelRequested = true;
            if isempty(app.Session) || ~any(string(app.Session.state) == ...
                    ["running","queued"])
                app.appendProgress("No study is running.");
                app.setBusyState(false);
                return
            end
            app.Session.cancel();
            app.Session.poll();
            app.syncSessionView();
            if any(string(app.Session.state) == ...
                    ["completed","cancelled","failed"])
                app.finishSession();
            else
                app.appendProgress("Cancellation requested...");
            end
        end

        function LoadButtonPushed(app,~,~)
            [fileName,pathName] = uigetfile("*.mat","Load ramp-speed study");
            if isequal(fileName,0)
                return
            end
            try
                app.loadStudyFromFile(fullfile(pathName,fileName));
            catch ME
                app.showError(ME);
            end
        end

        function SaveButtonPushed(app,~,~)
            if isempty(app.CurrentStudy) && ...
                    (isempty(app.Session) || isempty(app.Session.study.runs))
                app.appendProgress("Nothing to save.");
                return
            end
            [fileName,pathName] = uiputfile("*.mat","Save ramp-speed study");
            if isequal(fileName,0)
                return
            end
            try
                app.Session.save(fullfile(pathName,fileName));
                app.appendProgress("Study saved: " + string(fileName));
            catch ME
                app.showError(ME);
            end
        end

        function ExportButtonPushed(app,~,~)
            if isempty(app.CurrentStudy) && ...
                    (isempty(app.Session) || isempty(app.Session.study.runs))
                app.appendProgress("Nothing to export.");
                return
            end
            [fileName,pathName] = uiputfile("*.mat","Export ramp-speed study");
            if isequal(fileName,0)
                return
            end
            try
                [~,baseName,~] = fileparts(fileName);
                result = app.Session.export(pathName,struct("baseName",baseName));
                app.appendProgress("Study exported: " + string(result.studyMat));
            catch ME
                app.showError(ME);
            end
        end

        function ClearButtonPushed(app,~,~)
            try
                if ~isempty(app.Session)
                    app.Session.clearResults();
                end
                app.syncSessionView();
                app.LastEvents = struct.empty(0,1);
                app.LastPlotData = [];
                app.LastComparison = [];
                app.LastInspectorTable = table();
                app.ProgressTextArea.Value = "Ready.";
                app.clearAxes();
                app.InspectorTable.Data = table();
                app.setBusyState(false);
            catch ME
                app.showError(ME);
            end
        end
    end

    methods (Access = private)
        function createComponents(app)
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            app.UIFigure = uifigure("Visible","off");
            app.UIFigure.Name = "Ramp-Speed Study";
            app.UIFigure.CloseRequestFcn = @(~,~)delete(app);
            app.RootGridLayout = uigridlayout(app.UIFigure,[1 2]);
            app.SetupPanel = uipanel(app.RootGridLayout);
            app.SetupPanel.Title = "Run / Setup";
            app.SetupGridLayout = uigridlayout(app.SetupPanel,[31 2]);
            app.SetupGridLayout.RowHeight{2} = "120x";
            app.SetupGridLayout.RowHeight{30} = "90x";
            heading = uilabel(app.SetupGridLayout,"Text", ...
                "Available setups");
            heading.FontWeight = "bold";
            heading.Layout.Row = 1;
            heading.Layout.Column = [1 2];
            app.SetupTable = uitable(app.SetupGridLayout);
            app.SetupTable.ColumnName = {"ID","Label","Rear ARB (N m/rad)","Front spring (lb/in)", ...
                "Rear spring (lb/in)","Front ride height","Rear ride height","Driver weight (kg)","Rear weight dist. (%)", ...
                "Aero map","R_sf","Source"};
            app.SetupTable.Multiselect = "on";
            app.SetupTable.CellSelectionCallback = ...
                @(src,event)app.setupSelectionChanged(event);
            makeLabel(app.SetupGridLayout,"Setup label",3,1);
            app.SetupLabelEditField = uieditfield(app.SetupGridLayout, ...
                "text","Value","");
            app.SetupLabelEditField.ValueChangedFcn = ...
                @(src,event)app.setupEditorChanged(src,event);
            makeLabel(app.SetupGridLayout,"Rear ARB (N m/rad)",4,1);
            app.RearArbDropDown = uidropdown(app.SetupGridLayout, ...
                "Items",{'-'},"ItemsData",0,"Value",0);
            app.RearArbDropDown.ValueChangedFcn = ...
                @(src,event)app.setupEditorChanged(src,event);
            makeLabel(app.SetupGridLayout,"Front spring (lb/in)",5,1);
            app.FrontSpringDropDown = uidropdown(app.SetupGridLayout, ...
                "Items",{'-'},"ItemsData",0,"Value",0);
            app.FrontSpringDropDown.ValueChangedFcn = ...
                @(src,event)app.setupEditorChanged(src,event);
            makeLabel(app.SetupGridLayout,"Rear spring (lb/in)",6,1);
            app.RearSpringDropDown = uidropdown(app.SetupGridLayout, ...
                "Items",{'-'},"ItemsData",0,"Value",0);
            app.RearSpringDropDown.ValueChangedFcn = ...
                @(src,event)app.setupEditorChanged(src,event);
            makeLabel(app.SetupGridLayout,"Front ride height (length units)",7,1);
            app.FrontRideHeightEditField = uieditfield(app.SetupGridLayout, ...
                "numeric","Value",0);
            app.FrontRideHeightEditField.ValueChangedFcn = ...
                @(src,event)app.setupEditorChanged(src,event);
            makeLabel(app.SetupGridLayout,"Rear ride height (length units)",8,1);
            app.RearRideHeightEditField = uieditfield(app.SetupGridLayout, ...
                "numeric","Value",0);
            app.RearRideHeightEditField.ValueChangedFcn = ...
                @(src,event)app.setupEditorChanged(src,event);
            makeLabel(app.SetupGridLayout,"Driver weight (kg)",9,1);
            app.DriverWeightEditField = uieditfield(app.SetupGridLayout, ...
                "numeric","Value",0,"Limits",[0 Inf]);
            app.DriverWeightEditField.ValueChangedFcn = ...
                @(src,event)app.setupEditorChanged(src,event);
            makeLabel(app.SetupGridLayout,"Rear weight distribution (%)",10,1);
            app.RearWeightDistributionEditField = uieditfield( ...
                app.SetupGridLayout,"numeric","Value",51.2, ...
                "Limits",[0.01 99.99]);
            app.RearWeightDistributionEditField.ValueChangedFcn = ...
                @(src,event)app.setupEditorChanged(src,event);
            makeLabel(app.SetupGridLayout,"Aero map",11,1);
            app.AeroMapDropDown = uidropdown(app.SetupGridLayout, ...
                "Items",{'-'},"ItemsData",{''},"Value","");
            app.AeroMapDropDown.ValueChangedFcn = ...
                @(src,event)app.setupEditorChanged(src,event);
            makeLabel(app.SetupGridLayout,"Car role",12,1);
            app.CarRoleDropDown = uidropdown(app.SetupGridLayout, ...
                "Items",{'Lap car','Acceleration car'}, ...
                "ItemsData",{'lap','acceleration'},"Value","lap");
            app.CarRoleDropDown.ValueChangedFcn = ...
                @(src,event)app.caseSelectionChanged(src,event);
            app.CarRoleDropDown.Visible = "off";
            app.SetupGridLayout.RowHeight{12} = 0;
            makeLabel(app.SetupGridLayout,"Ramp type",13,1);
            app.RampTypeDropDown = uidropdown(app.SetupGridLayout, ...
                "Items",{'Lateral limit','Pure longitudinal'}, ...
                "ItemsData",{'lateral','longitudinal'},"Value","lateral");
            app.RampTypeDropDown.ValueChangedFcn = ...
                @(src,event)app.rampTypeChanged(src,event);
            makeLabel(app.SetupGridLayout,"Lateral mode",14,1);
            app.LateralModeDropDown = uidropdown(app.SetupGridLayout, ...
                "Items",{'Coast','Balanced'}, ...
                "ItemsData",{'coast','balanced'},"Value","coast");
            makeLabel(app.SetupGridLayout,"Speed start",15,1);
            app.SpeedStartEditField = uieditfield(app.SetupGridLayout, ...
                "numeric","Value",5,"Limits",[0 Inf]);
            makeLabel(app.SetupGridLayout,"Speed stop",16,1);
            app.SpeedStopEditField = uieditfield(app.SetupGridLayout, ...
                "numeric","Value",25,"Limits",[0 Inf]);
            makeLabel(app.SetupGridLayout,"Speed step",17,1);
            app.SpeedStepEditField = uieditfield(app.SetupGridLayout, ...
                "numeric","Value",5,"Limits",[0 Inf]);
            vectorLabel = uilabel(app.SetupGridLayout,"Text", ...
                "Explicit speeds");
            vectorLabel.Layout.Row = 18;
            vectorLabel.Layout.Column = 1;
            app.SpeedVectorEditField = uieditfield(app.SetupGridLayout, ...
                "text","Value","");
            app.SpeedVectorEditField.Placeholder = "e.g. 5, 7.5, 10";
            makeLabel(app.SetupGridLayout,"Ramp points",19,1);
            app.NRampEditField = uieditfield(app.SetupGridLayout, ...
                "numeric","Value",10,"Limits",[0 Inf], ...
                "RoundFractionalValues","on");
            makeLabel(app.SetupGridLayout,"Bisections",20,1);
            app.NBisectEditField = uieditfield(app.SetupGridLayout, ...
                "numeric","Value",8,"Limits",[-Inf Inf], ...
                "RoundFractionalValues","on");
            makeLabel(app.SetupGridLayout,"Residual tolerance",21,1);
            app.ResidualToleranceEditField = uieditfield( ...
                app.SetupGridLayout,"numeric","Value",1e-6,"Limits",[0 Inf]);
            makeLabel(app.SetupGridLayout,"Execution",22,1);
            app.ExecutionModeDropDown = uidropdown(app.SetupGridLayout, ...
                "Items",{'Serial','Parallel'}, ...
                "ItemsData",{'serial','parallel'},"Value","serial");
            makeLabel(app.SetupGridLayout,"Workers",23,1);
            app.WorkersEditField = uieditfield(app.SetupGridLayout, ...
                "numeric","Value",2,"Limits",[0 Inf], ...
                "RoundFractionalValues","on");
            makeLabel(app.SetupGridLayout,"Units",24,1);
            app.UnitDropDown = uidropdown(app.SetupGridLayout, ...
                "Items",{'SI','Imperial'}, ...
                "ItemsData",{'SI','imperial'},"Value","SI");
            app.UnitDropDown.ValueChangedFcn = ...
                @(src,event)app.unitChanged(src,event);
            makeLabel(app.SetupGridLayout,"Solver profile",25,1);
            app.SolverProfileDropDown = uidropdown(app.SetupGridLayout, ...
                "Items",{'Accurate','Fast preview','Approximate aero preview'}, ...
                "ItemsData",{'accurate','fastPreview','approximateAeroPreview'}, ...
                "Value","accurate");
            makeLabel(app.SetupGridLayout,"Speed grid",26,1);
            app.SpeedGridModeDropDown = uidropdown(app.SetupGridLayout, ...
                "Items",{'Fixed','Adaptive preview','Adaptive accurate', ...
                'Adaptive high accuracy'}, ...
                "ItemsData",{'fixed','preview','accurate','highAccuracy'}, ...
                "Value","accurate");
            makeLabel(app.SetupGridLayout,"Baseline",27,1);
            app.BaselineDropDown = uidropdown(app.SetupGridLayout);
            app.BaselineDropDown.ValueChangedFcn = ...
                @(src,event)app.baselineChanged(src,event);
            buttonGrid = uigridlayout(app.SetupGridLayout,[3 3]);
            buttonGrid.Layout.Row = 28;
            buttonGrid.Layout.Column = [1 2];
            buttonGrid.ColumnWidth = {"1x","1x","1x"};
            buttonGrid.RowHeight = {"1x","1x","1x"};
            app.RunButton = uibutton(buttonGrid,"Text","Run", ...
                "ButtonPushedFcn",@(src,event)app.RunButtonPushed(src,event));
            app.CancelButton = uibutton(buttonGrid,"Text","Cancel", ...
                "Enable","off", ...
                "ButtonPushedFcn",@(src,event)app.CancelButtonPushed(src,event));
            app.ClearButton = uibutton(buttonGrid,"Text","Clear", ...
                "ButtonPushedFcn",@(src,event)app.ClearButtonPushed(src,event));
            app.LoadButton = uibutton(buttonGrid,"Text","Load", ...
                "ButtonPushedFcn",@(src,event)app.LoadButtonPushed(src,event));
            app.SaveButton = uibutton(buttonGrid,"Text","Save", ...
                "ButtonPushedFcn",@(src,event)app.SaveButtonPushed(src,event));
            app.ExportButton = uibutton(buttonGrid,"Text","Export", ...
                "ButtonPushedFcn",@(src,event)app.ExportButtonPushed(src,event));
            app.DuplicateSetupButton = uibutton(buttonGrid,"Text","Duplicate", ...
                "ButtonPushedFcn",@(src,event)app.duplicateSelectedSetup());
            app.DeleteSetupButton = uibutton(buttonGrid,"Text","Delete", ...
                "ButtonPushedFcn",@(src,event)app.deleteSelectedSetup());
            progressLabel = uilabel(app.SetupGridLayout,"Text", ...
                "Progress");
            progressLabel.FontWeight = "bold";
            progressLabel.Layout.Row = 29;
            progressLabel.Layout.Column = [1 2];
            app.ProgressTextArea = uitextarea(app.SetupGridLayout, ...
                "Editable","off","Value","Ready.");
            hint = uilabel(app.SetupGridLayout,"Text", ...
                "Selected setups are retained across runs.");
            hint.FontAngle = "italic";
            hint.Layout.Row = 31;
            hint.Layout.Column = [1 2];
            app.AnalysisPanel = uipanel(app.RootGridLayout);
            analysisGrid = uigridlayout(app.AnalysisPanel,[1 1]);
            analysisGrid.Padding = [0 0 0 0];
            analysisGrid.RowHeight = {"1x"};
            analysisGrid.ColumnWidth = {"1x"};
            app.MainTabGroup = uitabgroup(analysisGrid);
            app.CapabilityTab = uitab(app.MainTabGroup,"Title","Capability");
            capabilityGrid = uigridlayout(app.CapabilityTab,[1 1]);
            capabilityGrid.Padding = [12 12 12 12];
            app.CapabilityAxes = uiaxes(capabilityGrid);
            title(app.CapabilityAxes,"Capability");
            xlabel(app.CapabilityAxes,"speed (m/s)");
            app.BalanceTab = uitab(app.MainTabGroup,"Title","Balance");
            balanceGrid = uigridlayout(app.BalanceTab,[1 1]);
            balanceGrid.Padding = [12 12 12 12];
            app.BalanceAxes = uiaxes(balanceGrid);
            title(app.BalanceAxes,"Balance");
            app.AeroLoadsTab = uitab(app.MainTabGroup,"Title","Aero & Loads");
            aeroLoadsGrid = uigridlayout(app.AeroLoadsTab,[1 1]);
            aeroLoadsGrid.Padding = [12 12 12 12];
            app.AeroLoadsAxes = uiaxes(aeroLoadsGrid);
            title(app.AeroLoadsAxes,"Aero & Loads");
            app.SuspensionTab = uitab(app.MainTabGroup,"Title","Suspension");
            suspensionGrid = uigridlayout(app.SuspensionTab,[1 1]);
            suspensionGrid.Padding = [12 12 12 12];
            app.SuspensionAxes = uiaxes(suspensionGrid);
            title(app.SuspensionAxes,"Suspension");
            app.RawRampTab = uitab(app.MainTabGroup,"Title","Raw Ramp");
            rawRampGrid = uigridlayout(app.RawRampTab,[1 1]);
            rawRampGrid.Padding = [12 12 12 12];
            app.RawRampAxes = uiaxes(rawRampGrid);
            title(app.RawRampAxes,"Raw Ramp");
            app.InspectorDataTab = uitab(app.MainTabGroup, ...
                "Title","Inspector/Data");
            inspectorGrid = uigridlayout(app.InspectorDataTab,[1 1]);
            inspectorGrid.Padding = [8 8 8 8];
            app.InspectorTable = uitable(inspectorGrid);
        end

        function initializeSetupEditor(app)
            if isempty(app.BaselineConfig) || ...
                    isempty(fieldnames(app.BaselineConfig))
                app.SetupReadOnly = true;
                app.refreshSetupEditor();
                return
            end
            options = app.BaselineConfig.options;
            app.RearArbDropDown.Items = cellstr(compose("%g", ...
                options.rearArbStiffness_NmPerRad));
            app.RearArbDropDown.ItemsData = ...
                options.rearArbStiffness_NmPerRad;
            app.FrontSpringDropDown.Items = cellstr(compose("%g", ...
                options.frontSpringRate_lb_in));
            app.FrontSpringDropDown.ItemsData = ...
                options.frontSpringRate_lb_in;
            app.RearSpringDropDown.Items = cellstr(compose("%g", ...
                options.rearSpringRate_lb_in));
            app.RearSpringDropDown.ItemsData = ...
                options.rearSpringRate_lb_in;
            catalog = rampSpeed.aeroMapCatalog();
            app.AeroMapDropDown.Items = cellstr(string({catalog.label}));
            app.AeroMapDropDown.ItemsData = cellstr(string({catalog.id}));
            app.refreshSetupEditor();
        end

        function refreshCaseCatalog(app)
            if ~isempty(app.SetupSpecs) && isstruct(app.BaselineConfig) && ...
                    ~isempty(fieldnames(app.BaselineConfig))
                [cars,cases,designTable] = rampSpeed.buildSetupCatalog( ...
                    app.BaselineConfig,app.SetupSpecs);
                app.Cars = cars;
                app.Cases = cases;
                app.DesignTable = designTable;
            else
                role = string(app.CarRoleDropDown.Value);
                app.Cases = rampSpeed.setupCaseCatalog(app.Cars, ...
                    app.DesignTable,role);
            end
            app.SelectedCaseIds = string({app.Cases.id}).';
            if isempty(app.Cases)
                app.BaselineCaseId = "";
            elseif isfield(app.Cases,'isBaseline')
                baselineIndex = find([app.Cases.isBaseline],1);
                if isempty(baselineIndex), baselineIndex = 1; end
                app.BaselineCaseId = string(app.Cases(baselineIndex).id);
            else
                app.BaselineCaseId = string(app.Cases(1).id);
            end
            app.refreshSetupTable();
            app.refreshSetupEditor();
        end

        function rebuildSetupCatalog(app)
            [cars,cases,designTable] = rampSpeed.buildSetupCatalog( ...
                app.BaselineConfig,app.SetupSpecs);
            app.Cars = cars;
            app.Cases = cases;
            app.DesignTable = designTable;
            app.SelectedCaseIds = string({cases.id}).';
            baselineIndex = find([cases.isBaseline],1);
            if isempty(baselineIndex), baselineIndex = 1; end
            app.BaselineCaseId = string(cases(baselineIndex).id);
        end

        function refreshSetupTable(app)
            if isempty(app.Cases)
                if ~isempty(app.SetupSpecs)
                    app.SetupTable.ColumnName = {"ID","Label","Rear ARB (N m/rad)","Front spring (lb/in)", ...
                        "Rear spring (lb/in)","Front ride height","Rear ride height","Driver weight (kg)","Rear weight dist. (%)", ...
                        "Aero map","R_sf","Source"};
                    app.SetupTable.Data = table(strings(0,1),strings(0,1), ...
                        zeros(0,1),zeros(0,1),zeros(0,1),zeros(0,1), ...
                        zeros(0,1),zeros(0,1),zeros(0,1),strings(0,1),zeros(0,1),strings(0,1), ...
                        'VariableNames',{'id','label', ...
                        'rearArbStiffness_NmPerRad','frontSpringRate_lb_in', ...
                        'rearSpringRate_lb_in','frontRideHeight_in', ...
                        'rearRideHeight_in','driverWeight_kg', ...
                        'rearWeightDistribution_percent','aeroMapId','R_sf','source'});
                else
                    app.SetupTable.ColumnName = {"ID","Label","Role","Source"};
                    app.SetupTable.Data = table(strings(0,1),strings(0,1), ...
                        strings(0,1),strings(0,1), ...
                        'VariableNames',{'id','label','role','source'});
                end
                app.BaselineDropDown.Items = {};
                app.BaselineDropDown.ItemsData = {};
                app.BaselineDropDown.Value = {};
                return
            end
            ids = string({app.Cases.id}).';
            labels = string({app.Cases.label}).';
            sources = string({app.Cases.source}).';
            if isfield(app.Cases,'setupSpec')
                specs = [app.Cases.setupSpec];
                derived = [app.Cases.derived];
                app.SetupTable.ColumnName = {"ID","Label","Rear ARB (N m/rad)","Front spring (lb/in)", ...
                    "Rear spring (lb/in)","Front ride height","Rear ride height","Driver weight (kg)","Rear weight dist. (%)", ...
                    "Aero map","R_sf","Source"};
                app.SetupTable.Data = table(ids,labels, ...
                    [specs.rearArbStiffness_NmPerRad].', ...
                    [specs.frontSpringRate_lb_in].', ...
                    [specs.rearSpringRate_lb_in].', ...
                    [specs.frontRideHeight_in].', ...
                    [specs.rearRideHeight_in].', ...
                    [specs.driverWeight_kg].', ...
                    [specs.rearWeightDistribution_percent].', ...
                    string({specs.aeroMapId}).', ...
                    [derived.R_sf].',sources, ...
                    'VariableNames',{'id','label', ...
                    'rearArbStiffness_NmPerRad','frontSpringRate_lb_in', ...
                    'rearSpringRate_lb_in','frontRideHeight_in', ...
                    'rearRideHeight_in','driverWeight_kg', ...
                        'rearWeightDistribution_percent','aeroMapId','R_sf','source'});
            else
                app.SetupTable.ColumnName = {"ID","Label","Role","Source"};
                roles = string({app.Cases.carRole}).';
                app.SetupTable.Data = table(ids,labels,roles,sources, ...
                    'VariableNames',{'id','label','role','source'});
            end
            app.BaselineDropDown.Items = cellstr(ids);
            app.BaselineDropDown.ItemsData = cellstr(ids);
            if isempty(app.BaselineCaseId) || ~ismember(app.BaselineCaseId,ids)
                app.BaselineCaseId = ids(1);
            end
            app.BaselineDropDown.Value = char(app.BaselineCaseId);
        end

        function refreshSetupEditor(app)
            editable = false;
            if ~isempty(app.Cases) && isfield(app.Cases,'setupSpec') && ...
                    ~isempty(app.Cases)
                index = app.selectedSetupIndex();
                spec = app.Cases(index).setupSpec;
                app.SetupLabelEditField.Value = char(string(spec.label));
                app.RearArbDropDown.Value = spec.rearArbStiffness_NmPerRad;
                app.FrontSpringDropDown.Value = spec.frontSpringRate_lb_in;
                app.RearSpringDropDown.Value = spec.rearSpringRate_lb_in;
                app.FrontRideHeightEditField.Value = app.setupLengthToDisplay(spec.frontRideHeight_in);
                app.RearRideHeightEditField.Value = app.setupLengthToDisplay(spec.rearRideHeight_in);
                app.DriverWeightEditField.Value = spec.driverWeight_kg;
                app.RearWeightDistributionEditField.Value = spec.rearWeightDistribution_percent;
                app.AeroMapDropDown.Value = char(string(spec.aeroMapId));
                editable = ~app.SetupReadOnly && ...
                    ~(isfield(app.Cases(index),'isBaseline') && ...
                    app.Cases(index).isBaseline);
            end
            controls = {app.SetupLabelEditField,app.RearArbDropDown, ...
                app.FrontSpringDropDown,app.RearSpringDropDown, ...
                app.FrontRideHeightEditField,app.RearRideHeightEditField, ...
                app.DriverWeightEditField,app.RearWeightDistributionEditField, ...
                app.AeroMapDropDown};
            for controlIndex = 1:numel(controls)
                controls{controlIndex}.Enable = app.ternary(editable,"on","off");
            end
            app.DuplicateSetupButton.Enable = app.ternary( ...
                ~app.SetupReadOnly,"on","off");
            app.DeleteSetupButton.Enable = app.ternary(editable,"on","off");
        end

        function setupEditorChanged(app,~,~)
            if app.SetupEditorUpdating || app.SetupReadOnly || ...
                    isempty(app.SetupSpecs)
                return
            end
            index = app.selectedSetupIndex();
            if isfield(app.Cases(index),'isBaseline') && app.Cases(index).isBaseline
                app.refreshSetupEditor();
                return
            end
            app.SetupEditorUpdating = true;
            try
                setup = app.Cases(index).setupSpec;
                setup.label = string(app.SetupLabelEditField.Value);
                setup.rearArbStiffness_NmPerRad = app.RearArbDropDown.Value;
                setup.frontSpringRate_lb_in = app.FrontSpringDropDown.Value;
                setup.rearSpringRate_lb_in = app.RearSpringDropDown.Value;
                setup.frontRideHeight_in = app.setupLengthFromDisplay(app.FrontRideHeightEditField.Value);
                setup.rearRideHeight_in = app.setupLengthFromDisplay(app.RearRideHeightEditField.Value);
                setup.driverWeight_kg = app.DriverWeightEditField.Value;
                setup.rearWeightDistribution_percent = app.RearWeightDistributionEditField.Value;
                setup.aeroMapId = string(app.AeroMapDropDown.Value);
                app.editSelectedSetup(setup);
            catch ME
                app.showError(ME);
                app.refreshSetupEditor();
            end
            app.SetupEditorUpdating = false;
        end

        function index = selectedSetupIndex(app)
            if isempty(app.Cases)
                error("rampSpeed:noSetupSelected","No setup is available.");
            end
            ids = string({app.Cases.id});
            wanted = string(app.SelectedCaseIds);
            if isempty(wanted)
                index = 1;
                return
            end
            index = find(ids == wanted(1),1);
            if isempty(index), index = 1; end
        end

        function value = ternary(~,condition,whenTrue,whenFalse)
            if condition, value = whenTrue; else, value = whenFalse; end
        end

        function setupSelectionChanged(app,event)
            if isempty(event) || ~isfield(event,"Indices") || ...
                    isempty(event.Indices)
                app.SelectedCaseIds = string({app.Cases.id}).';
            else
                rows = unique(event.Indices(:,1));
                rows = rows(rows >= 1 & rows <= numel(app.Cases));
                if isempty(rows)
                    app.SelectedCaseIds = string({app.Cases.id}).';
                else
                    ids = string({app.Cases(rows).id}).';
                    app.SelectedCaseIds = ids;
                    try
                        if ~isempty(app.Session)
                            app.Session.selectCases(ids);
                        end
                    catch ME
                        app.showError(ME);
                    end
                    if ~ismember(app.BaselineCaseId,ids)
                        app.BaselineCaseId = ids(1);
                        app.BaselineDropDown.Value = char(ids(1));
                    end
                end
            end
            app.refreshSetupEditor();
        end

        function caseSelectionChanged(app,~,~)
            app.refreshCaseCatalog();
        end

        function rampTypeChanged(app,~,~)
            % Ramp type selects the solver only; all ramp modes share setup cars.
            try
                if ~isempty(app.Session)
                    app.Session.setRampType(string(app.RampTypeDropDown.Value));
                end
            catch ME
                app.showError(ME);
            end
            app.refreshSetupTable();
            app.refreshSetupEditor();
        end
        function unitChanged(app,~,~)
            app.updateUnitSelection();
            app.refreshSetupEditor();
            app.refreshAnalysis();
        end

        function value = setupLengthToDisplay(app,valueIn)
            if string(app.DisplayUnits.length) == "m"
                value = double(valueIn)*0.0254;
            else
                value = double(valueIn);
            end
        end

        function value = setupLengthFromDisplay(app,valueDisplay)
            if string(app.DisplayUnits.length) == "m"
                value = double(valueDisplay)/0.0254;
            else
                value = double(valueDisplay);
            end
        end

        function updateUnitSelection(app)
            if string(app.UnitDropDown.Value) == "imperial"
                app.DisplayUnits = struct("speed","mph", ...
                    "acceleration","g","force","lbf","length","in", ...
                    "angle","deg","angularRate","rps");
            else
                app.DisplayUnits = struct("speed","m/s", ...
                    "acceleration","m/s^2","force","N","length","m", ...
                    "angle","rad","angularRate","rad/s");
            end
        end

        function baselineChanged(app,~,~)
            app.BaselineCaseId = string(app.BaselineDropDown.Value);
            app.refreshAnalysis();
        end

        function request = buildRequest(app)
            rampType = lower(strtrim(string(app.RampTypeDropDown.Value)));
            if ~ismember(rampType,["lateral","longitudinal"])
                invalidRequest(app,"Ramp type must be lateral or longitudinal.");
            end
            mode = lower(strtrim(string(app.LateralModeDropDown.Value)));
            if ~ismember(mode,["coast","balanced"])
                invalidRequest(app,"Lateral mode must be coast or balanced.");
            end
            sharedSetupCatalog = ~isempty(app.SetupSpecs) && ...
                isstruct(app.BaselineConfig) && ...
                ~isempty(fieldnames(app.BaselineConfig));
            if sharedSetupCatalog
                role = "auto";
            else
                role = lower(strtrim(string(app.CarRoleDropDown.Value)));
                if ~ismember(role,["lap","acceleration"])
                    invalidRequest(app,"Car role must be lap or acceleration.");
                end
            end

            explicit = parseSpeedVector(app.SpeedVectorEditField.Value);
            if isempty(explicit)
                startSpeed = scalarNumber(app.SpeedStartEditField.Value);
                stopSpeed = scalarNumber(app.SpeedStopEditField.Value);
                stepSpeed = scalarNumber(app.SpeedStepEditField.Value);
                if ~isfinite(startSpeed) || ~isfinite(stopSpeed) || ...
                        ~isfinite(stepSpeed) || startSpeed <= 0 || ...
                        stopSpeed <= 0 || stepSpeed <= 0 || stopSpeed < startSpeed
                    invalidRequest(app, ...
                        "Start, stop, and step speeds must be positive with stop >= start.");
                end
                speeds = startSpeed:stepSpeed:stopSpeed;
                if isempty(speeds)
                    invalidRequest(app,"The generated speed vector is empty.");
                end
                if speeds(end) < stopSpeed
                    speeds(end+1) = stopSpeed;
                end
            else
                speeds = explicit;
            end
            if any(~isfinite(speeds)) || any(speeds <= 0)
                invalidRequest(app,"All speeds must be finite and positive.");
            end
            nRamp = scalarNumber(app.NRampEditField.Value);
            nBisect = scalarNumber(app.NBisectEditField.Value);
            tolerance = scalarNumber(app.ResidualToleranceEditField.Value);
            if ~isfinite(nRamp) || nRamp < 2 || nRamp ~= round(nRamp)
                invalidRequest(app,"nRamp must be an integer greater than or equal to 2.");
            end
            if ~isfinite(nBisect) || nBisect < 0 || nBisect ~= round(nBisect)
                invalidRequest(app,"nBisect must be a nonnegative integer.");
            end
            if ~isfinite(tolerance) || tolerance <= 0
                invalidRequest(app,"Residual tolerance must be greater than zero.");
            end

            execution = lower(strtrim(string(app.ExecutionModeDropDown.Value)));
            parallelRequested = execution == "parallel";
            workers = scalarNumber(app.WorkersEditField.Value);
            if ~isfinite(workers) || workers < 0 || workers ~= round(workers)
                invalidRequest(app,"Workers must be a nonnegative integer.");
            end

            solverProfile = string(app.SolverProfileDropDown.Value);
            speedGridMode = string(app.SpeedGridModeDropDown.Value);
            settings = struct("speeds",speeds,"mode",mode, ...
                "nRamp",nRamp,"nBisect",nBisect, ...
                "residualTolerance",tolerance,"verbose",false, ...
                "carRole",role,"solverProfile",solverProfile, ...
                "speedGrid",struct("mode",speedGridMode));
            request = struct("rampType",rampType,"carRole",role, ...
                "settings",settings,"parallelRequested",parallelRequested, ...
                "numWorkers",workers,"checkpointPath",app.CheckpointPath, ...
                "appVersion",app.AppVersion,"displayUnits",app.DisplayUnits);
            if ~isempty(app.RunCaseFcnForTest)
                request.runCaseFcn = app.RunCaseFcnForTest;
            end
        end

        function invalidRequest(~,message)
            error("rampSpeed:app:invalidRequest",string(message));
        end

        function [study,events] = runStudyNow(app,request,~)
            job = app.Session.start(request);
            app.Session.poll();
            study = job.study;
            events = job.progressEvents;
        end

        function onFutureComplete(app,study,events)
            if ~isvalid(app)
                return
            end
            app.handleStudyResult(study,events);
            app.stopSessionTimer();
        end

        function handleStudyResult(app,study,events)
            if ~isempty(app.Session)
                app.Session.acceptResult(study);
                app.syncSessionView();
            end
            app.CurrentStudy = study;
            app.LastEvents = events;
            app.setBusyState(false);
            app.refreshAnalysis();
            if isfield(study,"status")
                status = lower(string(study.status));
            else
                status = "unknown";
            end
            switch status
                case {"complete","completed"}
                    message = "Study complete.";
                case "cancelled"
                    message = "Study cancelled.";
                otherwise
                    message = "Study " + status + ".";
            end
            app.appendProgress(message);
        end

        function onProgressEvent(app,event)
            if ~isvalid(app) || ~isstruct(event) || ~isscalar(event)
                return
            end
            if isfield(event,"message")
                message = string(event.message);
            else
                message = "";
            end
            needsContext = strlength(strtrim(message)) == 0 || ...
                lower(strtrim(message)) == "study running...";
            if needsContext
                speed = NaN;
                if isfield(event,"speed_mps") && ~isempty(event.speed_mps)
                    candidate = double(event.speed_mps);
                    if isscalar(candidate)
                        speed = candidate;
                    end
                end
                if isfinite(speed)
                    message = sprintf("Solving vCar %.2f m/s",speed);
                elseif isfield(event,"phase") && string(event.phase) == "adaptive"
                    message = "Adaptive speed refinement...";
                else
                    message = "Study running...";
                end
            end
            value = string(app.ProgressTextArea.Value);
            value(end+1,1) = message;
            value = value(max(1,end-99):end);
            app.ProgressTextArea.Value = cellstr(value);
        end

        function setBusyState(app,isBusy)
            if isempty(app.UIFigure) || ~isvalid(app.UIFigure)
                return
            end
            readOnly = app.SetupReadOnly;
            if ~isempty(app.Session)
                readOnly = readOnly || app.Session.readOnly;
            end
            if isBusy
                app.RunButton.Enable = "off";
                app.CancelButton.Enable = "on";
                app.LoadButton.Enable = "off";
                app.SaveButton.Enable = "off";
                app.ClearButton.Enable = "off";
            else
                app.RunButton.Enable = app.ternary(~readOnly,"on","off");
                app.CancelButton.Enable = "off";
                app.LoadButton.Enable = "on";
                app.SaveButton.Enable = "on";
                app.ClearButton.Enable = "on";
            end
        end

        function syncSessionView(app)
            if isempty(app.Session)
                return
            end
            if ~isempty(app.Session.config)
                app.Cars = app.Session.cars;
                app.Cases = app.Session.cases;
                app.DesignTable = app.Session.designTable;
                app.SetupSpecs = app.Session.setupSpecifications;
                app.SelectedCaseIds = app.Session.selectedCaseIds;
                if ~isempty(app.Cases)
                    ids = string({app.Cases.id});
                    if isempty(app.BaselineCaseId) || ...
                            ~ismember(app.BaselineCaseId,ids)
                        baselineIndex = find([app.Cases.isBaseline],1);
                        if isempty(baselineIndex), baselineIndex = 1; end
                        app.BaselineCaseId = ids(baselineIndex);
                    end
                end
            end
            if app.Session.readOnly
                app.SetupReadOnly = true;
            elseif ~isempty(app.Session.config) && ~app.SetupReadOnly
                app.SetupReadOnly = false;
            end
            if isstruct(app.Session.study) && ...
                    isfield(app.Session.study,"runs") && ...
                    ~isempty(app.Session.study.runs)
                app.CurrentStudy = app.Session.study;
            elseif ~app.Session.readOnly
                app.CurrentStudy = [];
            end
        end

        function startSessionTimer(app)
            app.stopSessionTimer();
            app.SessionTimer = timer("ExecutionMode","fixedRate", ...
                "Period",0.1,"BusyMode","drop", ...
                "TimerFcn",@(~,~)app.pollSession());
            start(app.SessionTimer);
        end

        function stopSessionTimer(app)
            if isempty(app.SessionTimer)
                return
            end
            try
                stop(app.SessionTimer);
            catch
            end
            try
                delete(app.SessionTimer);
            catch
            end
            app.SessionTimer = [];
        end

        function pollSession(app)
            if isempty(app.Session) || ~isvalid(app)
                app.stopSessionTimer();
                return
            end
            app.Session.poll();
            app.syncSessionView();
            if any(string(app.Session.state) == ...
                    ["completed","cancelled","failed"])
                job = app.Session.job;
                app.stopSessionTimer();
                app.finishSession(job);
            end
        end

        function finishSession(app,job)
            if nargin < 2 || isempty(job)
                if ~isempty(app.Session)
                    job = app.Session.job;
                end
            end
            if isempty(job)
                return
            end
            app.Session.poll();
            app.syncSessionView();
            study = app.Session.study;
            events = job.progressEvents;
            if isstruct(study) && isscalar(study) && ...
                    isfield(study,"status")
                app.handleStudyResult(study,events);
            end
        end

        function cases = selectedCases(app)
            if isempty(app.Cases)
                cases = app.Cases;
                return
            end
            ids = string({app.Cases.id}).';
            wanted = string(app.SelectedCaseIds(:));
            if isempty(wanted)
                wanted = ids;
            end
            mask = ismember(ids,wanted);
            if ~any(mask)
                mask(:) = true;
            end
            cases = app.Cases(mask);
        end

        function loadStudyFromFile(app,fileName)
            study = rampSpeed.loadStudy(fileName,app.AppVersion);
            app.CurrentStudy = study;
            app.SetupReadOnly = true;
            app.Cars = cell(0,1);
            app.Cases = struct.empty(0,1);
            app.DesignTable = table();
            app.SetupSpecs = struct.empty(0,1);
            app.BaselineConfig = struct();
            if isfield(study,"setupSpecifications") && ...
                    ~isempty(study.setupSpecifications)
                try
                    [~,app.BaselineConfig] = carConfigBaseline();
                    app.SetupSpecs = study.setupSpecifications(:).';
                    [cars,cases,designTable] = rampSpeed.buildSetupCatalog( ...
                        app.BaselineConfig,app.SetupSpecs);
                    app.Cars = cars;
                    app.Cases = cases;
                    app.DesignTable = designTable;
                    app.SetupReadOnly = false;
                catch ME
                    app.SetupReadOnly = true;
                    app.appendProgress("Setup definitions unavailable: " + ...
                        string(ME.message));
                end
            end
            if app.SetupReadOnly && isfield(study,"cases") && ...
                    ~isempty(study.cases)
                app.Cases = study.cases(:);
                app.SelectedCaseIds = string({app.Cases.id}).';
                app.BaselineCaseId = app.SelectedCaseIds(1);
            elseif ~isempty(app.Cases)
                app.SelectedCaseIds = string({app.Cases.id}).';
                app.BaselineCaseId = app.SelectedCaseIds(1);
            end
            app.Session = rampSpeed.RampSpeedSession( ...
                app.Cars,app.Cases,struct( ...
                "rampType",string(app.RampTypeDropDown.Value), ...
                "appVersion",app.AppVersion, ...
                "onProgress",@(event)app.onProgressEvent(event)));
            app.Session.acceptResult(study);
            app.initializeSetupEditor();
            app.refreshSetupTable();
            app.refreshSetupEditor();
            app.refreshAnalysis();
            app.setBusyState(false);
            app.appendProgress("Study loaded: " + string(fileName));
        end

        function reloadCheckpointAfterCancel(app)
            if strlength(app.CheckpointPath) == 0 || ...
                    ~isfile(app.CheckpointPath)
                return
            end
            try
                study = rampSpeed.loadStudy(app.CheckpointPath,app.AppVersion);
                if ~isempty(app.Session)
                    app.Session.acceptResult(study);
                end
                app.CurrentStudy = study;
                app.refreshAnalysis();
            catch ME
                app.appendProgress("Checkpoint reload failed: " + string(ME.message));
            end
        end

        function refreshAnalysis(app)
            if isempty(app.CurrentStudy) || ...
                    ~isfield(app.CurrentStudy,"runs") || ...
                    isempty(app.CurrentStudy.runs)
                app.clearAxes();
                app.InspectorTable.Data = table();
                return
            end
            runs = app.CurrentStudy.runs;
            labels = string({runs.caseId}).';
            isLongitudinal = false;
            if isfield(app.CurrentStudy,"runMeta") && ...
                    isfield(app.CurrentStudy.runMeta,"rampType")
                isLongitudinal = string(app.CurrentStudy.runMeta.rampType) == ...
                    "longitudinal";
            elseif isfield(runs,"type")
                isLongitudinal = string(runs(1).type) == "longitudinal";
            end

            if isLongitudinal
                capabilityMetric = "drag";
                aeroMetric = "downforce";
                rawMetric = "raw_aLong";
            else
                capabilityMetric = "capability_free";
                aeroMetric = "downforce";
                rawMetric = "raw_aLat";
            end

            capabilityData = rampSpeed.buildPlotData(runs,capabilityMetric, ...
                struct("labels",labels));
            app.LastPlotData = app.prepareDisplayPlotData(capabilityData);
            app.CapabilityHandles = app.renderData(app.CapabilityAxes, ...
                app.LastPlotData);

            aeroData = rampSpeed.buildPlotData(runs,aeroMetric, ...
                struct("labels",labels));
            aeroData = app.prepareDisplayPlotData(aeroData);
            app.AeroLoadsHandles = app.renderData(app.AeroLoadsAxes,aeroData);

            suspensionData = rampSpeed.buildPlotData(runs,"front_ride_height", ...
                struct("labels",labels));
            suspensionData = app.prepareDisplayPlotData(suspensionData);
            app.SuspensionHandles = app.renderData(app.SuspensionAxes, ...
                suspensionData);

            rawData = rampSpeed.buildPlotData(runs,rawMetric, ...
                struct("labels",labels));
            rawData = app.prepareDisplayPlotData(rawData);
            app.RawRampHandles = app.renderData(app.RawRampAxes,rawData);

            app.LastComparison = [];
            app.ComparisonHandles = struct();
            baseline = string(app.BaselineCaseId);
            if isLongitudinal
                app.renderMessage(app.BalanceAxes, ...
                    "Balance is lateral-only for this run type.");
                app.BalanceHandles = struct();
            else
                balanceMetric = "handling_balance";
                balanceData = rampSpeed.buildPlotData(runs,balanceMetric, ...
                    struct("labels",labels));
                balanceData = app.prepareDisplayPlotData(balanceData);
                app.BalanceHandles = app.renderData(app.BalanceAxes, ...
                    balanceData);
                if numel(runs) > 1 && strlength(baseline) > 0
                    try
                        app.LastComparison = rampSpeed.buildComparison(runs, ...
                            balanceMetric,baseline,struct());
                        comparisonData = app.comparisonPlotData( ...
                            app.LastComparison);
                        comparisonData = app.prepareDisplayPlotData( ...
                            comparisonData);
                        app.ComparisonHandles = app.renderData( ...
                            app.BalanceAxes,comparisonData);
                        title(app.BalanceAxes,"Delta: variant - " + baseline);
                        ylabel(app.BalanceAxes,"Delta " + ...
                            string(comparisonData.yLabel));
                    catch ME
                        app.LastComparison = [];
                        app.ComparisonHandles = struct();
                        app.renderMessage(app.BalanceAxes, ...
                            "Comparison unavailable: " + string(ME.message));
                    end
                end
            end

            selection = struct("speedIndex",1,"pointIndex",1);
            try
                app.LastInspectorTable = rampSpeed.buildInspectorTable( ...
                    runs(1),selection,app.DisplayUnits);
                app.InspectorTable.Data = app.LastInspectorTable;
            catch
                app.LastInspectorTable = table();
                app.InspectorTable.Data = table();
            end
        end

        function handles = renderData(app,ax,data)
            app.clearAxisGraphics(ax);
            handles = rampSpeed.renderMetric(ax,data, ...
                struct("showLegend",true,"showWarnings",true));
        end

        function data = comparisonPlotData(~,comparison)
            data = struct("metricId",comparison.metricId, ...
                "metric",comparison.metric,"sourceLevel",comparison.sourceLevel, ...
                "xLabel",comparison.xLabel,"yLabel",comparison.yLabel, ...
                "series",comparison.series);
            for i = 1:numel(data.series)
                data.series(i).label = string(data.series(i).label) + ...
                    " - " + string(comparison.baselineId);
            end
        end

        function data = prepareDisplayPlotData(app,data)
            if isempty(data) || ~isfield(data,"metric")
                return
            end
            metric = data.metric;
            targetUnit = app.targetMetricUnit(metric);
            data.metric.displayUnits = targetUnit;
            data.xLabel = replace(string(data.xLabel),"m/s", ...
                string(app.DisplayUnits.speed));
            data.yLabel = app.replaceUnitLabel(data.yLabel, ...
                string(metric.displayUnits),targetUnit);
            speedFactor = 1;
            if string(app.DisplayUnits.speed) == "mph"
                speedFactor = 2.2369362920544;
            end
            for i = 1:numel(data.series)
                data.series(i).x = double(data.series(i).x) * speedFactor;
                data.series(i).values = app.convertMetricValues( ...
                    data.series(i).values,metric,targetUnit);
                if isfield(data.series(i),"groups") && ...
                        ~isempty(data.series(i).groups)
                    for j = 1:numel(data.series(i).groups)
                        data.series(i).groups(j).x = ...
                            double(data.series(i).groups(j).x) * speedFactor;
                        data.series(i).groups(j).values = ...
                            app.convertMetricValues(data.series(i).groups(j).values, ...
                            metric,targetUnit);
                    end
                end
            end
        end

        function unit = targetMetricUnit(app,metric)
            stored = string(metric.storedUnits);
            switch stored
                case "m/s^2"
                    unit = string(app.DisplayUnits.acceleration);
                case "N"
                    unit = string(app.DisplayUnits.force);
                case "m"
                    unit = string(app.DisplayUnits.length);
                case "rad"
                    unit = string(app.DisplayUnits.angle);
                case "rad/s"
                    unit = string(app.DisplayUnits.angularRate);
                case "rad/(m/s^2)"
                    if string(app.DisplayUnits.angle) == "rad" && ...
                            string(app.DisplayUnits.acceleration) == "m/s^2"
                        unit = "rad/(m/s^2)";
                    elseif string(app.DisplayUnits.angle) == "deg" && ...
                            string(app.DisplayUnits.acceleration) == "g"
                        unit = "deg/g";
                    else
                        unit = string(app.DisplayUnits.angle) + "/(" + ...
                            string(app.DisplayUnits.acceleration) + ")";
                    end
                otherwise
                    unit = string(metric.displayUnits);
            end
        end

        function values = convertMetricValues(~,values,metric,targetUnit)
            values = double(values);
            scale = double(metric.scale);
            if isempty(scale) || ~isfinite(scale) || scale == 0
                scale = 1;
            end
            raw = values ./ scale;
            stored = string(metric.storedUnits);
            targetUnit = string(targetUnit);
            switch stored
                case "m/s^2"
                    if targetUnit == "g"
                        values = raw ./ 9.80665;
                    else
                        values = raw;
                    end
                case "N"
                    if targetUnit == "lbf"
                        values = raw ./ 4.4482216152605;
                    else
                        values = raw;
                    end
                case "m"
                    if targetUnit == "in"
                        values = raw .* 39.37007874015748;
                    else
                        values = raw;
                    end
                case "rad"
                    if targetUnit == "deg"
                        values = raw .* 180/pi;
                    else
                        values = raw;
                    end
                case "rad/s"
                    if targetUnit == "rps"
                        values = raw ./ (2*pi);
                    else
                        values = raw;
                    end
                case "rad/(m/s^2)"
                    if targetUnit == "deg/g"
                        values = raw .* 9.80665 .* 180/pi;
                    elseif targetUnit == "deg/(m/s^2)"
                        values = raw .* 180/pi;
                    elseif targetUnit == "rad/g"
                        values = raw .* 9.80665;
                    else
                        values = raw;
                    end
                case "fraction"
                    if targetUnit == "%"
                        values = raw .* 100;
                    else
                        values = raw;
                    end
                otherwise
                    values = raw .* scale;
            end
        end

        function label = replaceUnitLabel(~,label,sourceUnit,targetUnit)
            label = string(label);
            sourceUnit = string(sourceUnit);
            targetUnit = string(targetUnit);
            if strlength(sourceUnit) > 0 && sourceUnit ~= targetUnit
                label = replace(label,"(" + sourceUnit + ")", ...
                    "(" + targetUnit + ")");
            end
        end
        function renderMessage(app,ax,message)
            app.clearAxisGraphics(ax);
            title(ax,message);
            grid(ax,"on");
        end

        function clearAxisGraphics(~,ax)
            if ~isgraphics(ax,"axes")
                return
            end
            children = allchild(ax);
            if ~isempty(children)
                delete(children);
            end
        end

        function clearAxes(app)
            axesList = [app.CapabilityAxes,app.BalanceAxes, ...
                app.AeroLoadsAxes,app.SuspensionAxes,app.RawRampAxes];
            for ax = axesList(:).'
                if isgraphics(ax)
                    app.clearAxisGraphics(ax);
                end
            end
        end

        function appendProgress(app,message)
            value = cellstr(string(app.ProgressTextArea.Value));
            value{end+1,1} = char(string(message));
            value = value(max(1,end-99):end);
            app.ProgressTextArea.Value = value;
        end

        function showError(app,ME)
            app.appendProgress("Error: " + string(ME.message));
        end
    end
end

function cars = defaultCars()
cars = {struct("name","Default lap car","source","RampSpeedApp"), ...
    struct("name","Default acceleration car","source","RampSpeedApp")};
end

function designTable = defaultDesignTable()
designTable = table("default","Default setup","RampSpeedApp", ...
    'VariableNames',{'id','label','source'});
end

function makeLabel(parent,textValue,row,column)
label = uilabel(parent,"Text",textValue);
label.Layout.Row = row;
label.Layout.Column = column;
end

function value = scalarNumber(raw)
value = double(raw);
if ~isscalar(value)
    value = NaN;
end
end

function values = parseSpeedVector(raw)
textValue = strtrim(string(raw));
if isscalar(textValue) && strlength(textValue) == 0
    values = [];
    return
end
if ~isscalar(textValue)
    values = NaN;
    return
end
clean = regexprep(char(textValue),'[,;\[\]]',' ');
tokens = split(strtrim(string(clean)));
tokens = tokens(strlength(tokens) > 0);
values = str2double(tokens).';
if isempty(values) || any(isnan(values))
    values = NaN;
end
end